classdef gameBoardGUI_rev2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        CoppeliaSimMATLABConnect4SimulatorLabel  matlab.ui.control.Label
        ConnectButton     matlab.ui.control.Button
        Image             matlab.ui.control.Image
        PlayButton        matlab.ui.control.Button
        Player2Lamp       matlab.ui.control.Lamp
        Player2LampLabel  matlab.ui.control.Label
        Player1Lamp       matlab.ui.control.Lamp
        Player1LampLabel  matlab.ui.control.Label
        ResetButton       matlab.ui.control.Button
        Column7Button     matlab.ui.control.Button
        Column6Button     matlab.ui.control.Button
        Column5Button     matlab.ui.control.Button
        Column4Button     matlab.ui.control.Button
        Column3Button     matlab.ui.control.Button
        Column2Button     matlab.ui.control.Button
        Column1Button     matlab.ui.control.Button
    end

    
    properties (Access = private)
        player      % Either = 1 or 2, depending on who's turn it is
        board       % Array containing all play spaces
        winner      % Boolean for if someone has won
        sim         % For connecting to CoppeliaSim API
        clientID    % ^^
        gripperID   % Joint ID for changing parent/child relationships
        Z_Motor     % Global ID for Z motor
        X_Motor     % Global ID for X motor
        camera      % Global ID for camera
        x_columnPositions = [0, 0.4, 0.8, 1.1, 1.45, 1.85, 2.2, 3.38, 3.76]
        y_columnPosition = -0.5
        z_columnPosition = 3.1
        playedPieces
        playedPiece_str
        P1cylinder
        P2cylinder
    end
    
    methods (Access = private)
        function addPiece(app, column)
            if size(app.board) == 0 % If play button has not been pressed, otherwise there will be an error
                app.PlayButtonPushed();
            end

            for i = 1:6
                current = app.board(7-i, column);
                if current == 0
                    app.board(7-i, column) = app.player;
                    disp(app.board)
                    pieceID = app.findPiece(i, column);
                    app.getPiece(pieceID, column)
        
                    % Add to playedPieces
                    app.playedPieces(7-i, column) = pieceID;   
                    disp(app.playedPieces)

                    % Check if won
                    if ~app.didWin() % If player did not win
                        app.changePlayer();
                    end

                    break
                end
            end
        end  


        function updateCamera(app, time)
            pauseTime = 0.5; % In seconds
            for i = 0:(time/pauseTime)
                [~,~,img] = app.sim.simxGetVisionSensorImage2(app.clientID, app.camera, 0, app.sim.simx_opmode_oneshot_wait);
                app.Image.ImageSource = img;
                pause(pauseTime)
            end
        end

        function changeParentChild(app, childID, gripTF)
            disp('Changing parent child')
            if gripTF == 1
                returnCode = app.sim.simxSetObjectParent(app.clientID, childID, app.gripperID, 1, app.sim.simx_opmode_blocking); %#ok<*NASGU> 
            else
                returnCode = app.sim.simxSetObjectParent(app.clientID, childID, -1, 1, app.sim.simx_opmode_blocking);
            end

        end

        function getPiece(app, pieceID, column)
            disp('Getting piece ID: ' + string(pieceID));
            
            % Get piece location
            [~, position] = app.sim.simxGetObjectPosition(app.clientID, pieceID, -1, app.sim.simx_opmode_oneshot_wait); % -1 indicates absolute position, streaming opmode for first call

            % Go to piece location
            app.moveGantry(position)
            app.updateCamera(0.5)

            % Change parent/child to grab piece
            app.changeParentChild(pieceID, 1);
            %app.updateCamera(0.5)

            % Move to drop off
            app.dropOff(column);
            %app.updateCamera(0.5)
            
            % Release piece
            app.changeParentChild(pieceID, 0);
            app.updateCamera(1.5);
        end

        function moveGantry(app, position)
            disp('Moving to ' + string(position))

            % Move X
            xMove = position(1) + 0.65;
            XreturnCode = app.sim.simxSetJointTargetPosition(app.clientID, app.X_Motor, xMove, app.sim.simx_opmode_blocking);
            app.updateCamera(4.5);

            % Move Z
            zMove = 3.5- position(3);
            ZreturnCode = app.sim.simxSetJointTargetPosition(app.clientID, app.Z_Motor, zMove, app.sim.simx_opmode_blocking);
            app.updateCamera(3);
        end

        function dropOff(app, column)
            disp('Dropping off @ column ' + string(column))
    
            % Move Z
            zMove = app.z_columnPosition;
            ZreturnCode = app.sim.simxSetJointTargetPosition(app.clientID, app.Z_Motor, 0, app.sim.simx_opmode_blocking);
            app.updateCamera(3)
    
            % Move X
            xMove = app.x_columnPositions(column);
            XreturnCode = app.sim.simxSetJointTargetPosition(app.clientID, app.X_Motor, xMove, app.sim.simx_opmode_blocking);
            app.updateCamera(4.5)
        end        
    
        function pieceID = findPiece(app, i , j)
            % Find first available chip for player from cylinder
            if app.player == 1
                piece_str = app.P1cylinder(1:4);
                app.P1cylinder = app.P1cylinder(5:end);
            else
                piece_str = app.P2cylinder(1:5);
                app.P2cylinder = app.P2cylinder(6:end);
            end
            disp(piece_str);
            app.playedPiece_str(i, j) = string(piece_str);
            % Get piece handle
            [~, pieceID] = app.sim.simxGetObjectHandle(app.clientID, piece_str, app.sim.simx_opmode_blocking);            
        end
    
    
        function changePlayer(app)
            if app.player == 1
               app.player = 2;
               app.Player1Lamp.Enable = "off";
               app.Player2Lamp.Enable = "on";
               disp("Player 2's turn")
            else
               app.player = 1;
               app.Player1Lamp.Enable = "on";
               app.Player2Lamp.Enable = "off";
               disp("Player 1's turn")
            end
        end
    
        function ret = didWin(app)
            % Find if there's 4 in a row
            counter = 0;
            lastVal = app.player;
            for rowIndex = 1:6
                for columnIndex = 1:7
                    val = app.board(rowIndex, columnIndex);
                    if val == lastVal
                        if val == app.player
                            counter = counter + 1;
                            if counter >= 3
                                ret = true;
                                disp("Player " + string(app.player) + " wins!")
                                winString = 'Player ' + string(app.player) + ' wins!';
                                msgbox(winString, title='Winner!');                            
                                return
                            end
                        end
                    end
                    lastVal = val; 
                end
            end
        
            % Find if there's 4 in a column
            counter = 0;
            lastVal = app.player;
            for columnIndex = 1:7
                for rowIndex = 1:6
                    val = app.board(rowIndex, columnIndex);
                    if val == lastVal
                        if val == app.player
                            counter = counter + 1;
                            if counter >= 3
                                ret = true;
                                disp("Player " + string(app.player) + " wins!")
                                winString = 'Player ' + string(app.player) + ' wins!';
                                msgbox(winString, title='Winner!');
                                return
                            end
                        end
                    end
                    lastVal = val;
                end
            end
            ret = false;
        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            disp("Reset")
            app.ResetButton.Text = 'Reseting';
            app.ResetButton.BackgroundColor = 'Red';
            for i = 1:6
                for j = 1:7
                    current = app.board(i, j);
                    if current ~= 0
                        pieceID = app.playedPieces(i, j);
                        piece_str = string(app.playedPiece_str(i, j));

                        [~, position] = app.sim.simxGetObjectPosition(app.clientID, pieceID, -1, app.sim.simx_opmode_oneshot_wait);
                        app.moveGantry(position);
                        pause(2)

                        app.changeParentChild(pieceID, 1);
                        pause(1)

                        app.dropOff(7 + current);
                        pause(2.5)
                        app.changeParentChild(pieceID, 0);

                        if current == 1
                            app.P1cylinder = append(piece_str, app.P1cylinder);
                        elseif current == 2
                            app.P2cylinder = append(piece_str, app.P2cylinder);

                        end
                    end
                end
            end            

            app.ResetButton.Text = 'Reset';
            app.ResetButton.BackgroundColor = 'white';

            % Reset app.board using Play button function            
            app.PlayButtonPushed();
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            disp("Play")
            app.PlayButton.Text = 'Playing';
            app.PlayButton.BackgroundColor = 'Green';
            app.board = [
                         [0,0,0,0,0,0,0];
                         [0,0,0,0,0,0,0];
                         [0,0,0,0,0,0,0];
                         [0,0,0,0,0,0,0];
                         [0,0,0,0,0,0,0];
                         [0,0,0,0,0,0,0];
                        ];
            app.playedPieces = app.board;
            app.playedPiece_str = app.board;
            if length(app.P1cylinder) < 20  % If this function isn't being called by the reset function
                app.P1cylinder = ['RED1', 'RED2', 'RED3', 'RED4', 'RED5', 'RED6','RED7', 'RED8', 'RED9', 'RED10', 'RED11', 'RED12','RED13'];
                app.P2cylinder = ['BLUE1', 'BLUE2', 'BLUE3', 'BLUE4', 'BLUE5', 'BLUE6','BLUE7', 'BLUE8', 'BLUE9', 'BLUE10', 'BLUE11', 'BLUE12','BLUE13'];
            end                
            app.player = 1;
            app.Player2Lamp.Enable = "off";
        end

        % Button pushed function: Column1Button
        function Column1ButtonPushed(app, event)
            app.addPiece(1);
        end

        % Button pushed function: Column2Button
        function Column2ButtonPushed(app, event)
            app.addPiece(2);
        end

        % Button pushed function: Column3Button
        function Column3ButtonPushed(app, event)
            app.addPiece(3);
        end

        % Button pushed function: Column4Button
        function Column4ButtonPushed(app, event)
            app.addPiece(4);    
        end

        % Button pushed function: Column5Button
        function Column5ButtonPushed(app, event)
            app.addPiece(5); 
        end

        % Button pushed function: Column6Button
        function Column6ButtonPushed(app, event)
            app.addPiece(6);
        end

        % Button pushed function: Column7Button
        function Column7ButtonPushed(app, event)
            app.addPiece(7);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.sim = remApi('remoteApi'); % Using the prototype file (remoteApiProto.m)
            app.sim.simxFinish(-1); % Close all opened connections
            app.clientID = app.sim.simxStart('127.0.0.1', 19999, true, true, 5000, 5);

            if (app.clientID > -1)
                disp('Connected to remote API server'); % Displays message in MATLAB
                app.sim.simxAddStatusbarMessage(app.clientID, 'Hello CoppeliaSim!', app.sim.simx_opmode_oneshot); % Displays message in Copellia
                pause(1)
                
                app.ConnectButton.BackgroundColor = 'Green';

                % Aquire handle for objects we want to read or manipulate
                [~, app.Z_Motor] = app.sim.simxGetObjectHandle(app.clientID, 'Z_MOTOR', app.sim.simx_opmode_blocking);
                [~, app.X_Motor] = app.sim.simxGetObjectHandle(app.clientID, 'X_MOTOR', app.sim.simx_opmode_blocking);
                [~, app.gripperID] = app.sim.simxGetObjectHandle(app.clientID, 'GRIPPER_JOINT', app.sim.simx_opmode_blocking);
                [~, app.camera] = app.sim.simxGetObjectHandle(app.clientID, 'Vision_sensor', app.sim.simx_opmode_blocking);
                [~,~,img] = app.sim.simxGetVisionSensorImage2(app.clientID, app.camera, 0, app.sim.simx_opmode_oneshot_wait);
                app.Image.ImageSource = img;
                %[~, position] =  app.sim.simxGetObjectPosition(app.clientID, app.gripperID, -1, app.sim.simx_opmode_streaming); % -1 indicates absolute position, streaming opmode for first call
                
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           
            % Close connection
            app.sim.simxFinish(-1);  % End connection
            app.sim.delete();
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Column1Button = uibutton(app.UIFigure, 'push');
            app.Column1Button.ButtonPushedFcn = createCallbackFcn(app, @Column1ButtonPushed, true);
            app.Column1Button.Text = 'Column 1';
            app.Column2Button = uibutton(app.UIFigure, 'push');
            app.Column2Button.ButtonPushedFcn = createCallbackFcn(app, @Column2ButtonPushed, true);
            app.Column2Button.Text = 'Column 2';
            app.Column3Button = uibutton(app.UIFigure, 'push');
            app.Column3Button.ButtonPushedFcn = createCallbackFcn(app, @Column3ButtonPushed, true);
            app.Column3Button.Text = 'Column 3';
            app.Column4Button = uibutton(app.UIFigure, 'push');
            app.Column4Button.ButtonPushedFcn = createCallbackFcn(app, @Column4ButtonPushed, true);
            app.Column4Button.Text = 'Column 4';
            app.Column5Button = uibutton(app.UIFigure, 'push');
            app.Column5Button.ButtonPushedFcn = createCallbackFcn(app, @Column5ButtonPushed, true);
            app.Column5Button.Text = 'Column 5';
            app.Column6Button = uibutton(app.UIFigure, 'push');
            app.Column6Button.ButtonPushedFcn = createCallbackFcn(app, @Column6ButtonPushed, true);
            app.Column6Button.Text = 'Column 6';
            app.Column7Button = uibutton(app.UIFigure, 'push');
            app.Column7Button.ButtonPushedFcn = createCallbackFcn(app, @Column7ButtonPushed, true);
            app.Column7Button.Text = 'Column 7';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Player1LampLabel = uilabel(app.UIFigure);
            app.Player1LampLabel.Text = 'Player 1';
            app.Player1Lamp = uilamp(app.UIFigure);
            app.Player2LampLabel = uilabel(app.UIFigure);
            app.Player2LampLabel.Text = 'Player 2';
            app.Player2Lamp = uilamp(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Image = uiimage(app.UIFigure);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.CoppeliaSimMATLABConnect4SimulatorLabel = uilabel(app.UIFigure);
            app.CoppeliaSimMATLABConnect4SimulatorLabel.Text = 'CoppeliaSim + MATLAB Connect4 Simulator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameBoardGUI_rev2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end